classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadVocalsButton           matlab.ui.control.Button
        LoadDrumsButton            matlab.ui.control.Button
        LoadInstrumentalButton     matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        TheWallOfSoundPluginLabel  matlab.ui.control.Label
        VolumeKnobLabel            matlab.ui.control.Label
        VolumeKnob                 matlab.ui.control.Knob
        UIAxes2                    matlab.ui.control.UIAxes
        TheWallOfSoundButton       matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        NoiseReductionCheckBox     matlab.ui.control.CheckBox
        PlayButton                 matlab.ui.control.Button
        PlayButton_2               matlab.ui.control.Button
        PlayButton_3               matlab.ui.control.Button
        ListentothemixButton       matlab.ui.control.Button
        SaveTheSpectorMixButton    matlab.ui.control.Button
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVocalsButton
        function loadvocals(app, event)
         global xm;   
         global Fs;        
         [FileName, PathName] = uigetfile({'*.wav; *.aif'});
         [x,Fs] = audioread([PathName FileName]);
          %converting to stereo
          xm = x(:,1);
          plot(app.UIAxes,x);
        end

        % Button pushed function: LoadDrumsButton
        function loaddrums(app, event)
          global x1m;
          global Fs; 
          [FileName, PathName] = uigetfile({'*.wav; *.aif'});
          [ x1,  Fs] = audioread([PathName FileName]);
           %converting to stereo
           x1m = x1(:,1);
           plot(app.UIAxes_2,x1);
        end

        % Button pushed function: LoadInstrumentalButton
        function loadinstrument(app, event)
           global x2m;
           global Fs; 
           [FileName, PathName] = uigetfile({'*.wav; *.aif'});
           [ x2,Fs] = audioread([PathName FileName]);
            %converting to stereo
            x2m = x2(:,1);
            plot(app.UIAxes_3,x2);
        end

        % Value changed function: NoiseReductionCheckBox
        function noisereduction(app, event)
value = app.NoiseReductionCheckBox.Value;
global xfilter;
global x1filter ;
global x2filter;
global xm;
global x1m;
global x2m;
global Fs;
if value == 1
o = 2;
w = [60 8000]*2/ Fs;
[b,a] = butter(o,w,'bandpass');
 xfilter = filter(b,a,xm);
 x1filter = filter(b,a,x1m);
 x2filter = filter(b,a,x2m);
elseif value == 0
 xfilter = xm;
 x1filter = x1m;
 x2filter = x2m;
end
plot(app.UIAxes,xfilter);
plot(app.UIAxes_2,x1filter);
plot(app.UIAxes_3,x2filter);
                
        end

        % Button pushed function: TheWallOfSoundButton
        function WallOfSound(app, event)
global xfilter;
global x1filter ;
global x2filter;
global xm;
global x1m;
global x2m;
global Fs;
global yo;
global yx;
global audioout;
%reverb for vocals
d1 = round(Fs*0.0008); 
d2 = round(Fs*0.0005); 
cf = 0.5; 
y = filter([1 zeros(1,d1) cf],[1 zeros(1,d2) -cf],xfilter);
%reverb for drums
d3 = round(Fs*0.0078); 
d4 = round(Fs*0.00075); 
cf = 0.6; 
y1 = filter([1 zeros(1,d3) cf],[1 zeros(1,d4) -cf],x1filter);
%chorus effect for instrument
k = zeros(1,length(x2filter));
buff = zeros(1,500);  
%creating an LFO
sdx = 200; 
lf = 0.6;                                                                
st = abs(sin(2*pi*lf*[0:length(x2filter)-1]/Fs)); 
bi = round(st*(length(buff)-sdx))+sdx;
for i=1:length(x2filter)
    k(i)=x2filter(i)+buff(bi(i));
    buff(2:end)=buff(1:end-1); 
    buff(1)= x2filter(i);            
end
%layering
y3 = k'+x2filter;
yo = 0;
m = max(length(y),length(y1));
m1 = max(length(y),length(y3));
%making the audio lengths equal for addition
if m1<=m
buffer = zeros(m,1);
buffer(1:length(y)) = y;
buffer1 = zeros(m,1);
buffer1(1:length(y1)) = y1;
buffer2 = zeros(m,1);
buffer2(1:length(y3)) = y3;
yo = buffer + buffer1 +buffer2;
%reverbchambersimulation
dx = round(Fs*0.00075); 
dy = round(Fs*0.005); 
cf1 = 0.5; 
rc = filter([1 zeros(1,dx) cf1],[1 zeros(1,dy) -cf1],yo)
yx = yo + rc;
%compressor
dRC = compressor('AttackTime',0.01,'SampleRate',Fs);
audioout = dRC(yx);
elseif m<=m1
buffe = zeros(m,1);
buffe(1:length(y)) = y;
buffe1 = zeros(m,1);
buffe1(1:length(y1)) = y1;
buffe2 = zeros(m,1);
buffe2(1:length(y3)) = y3;
yo = buffe + buffe1 +buffe2;
%reverbchambersimulation
dx = round(Fs*0.00075); 
dy = round(Fs*0.005); 
cf1 = 0.5; 
rc = filter([1 zeros(1,dx) cf1],[1 zeros(1,dy) -cf1],yo)
yx = yo + rc;
%compressor
dRC = compressor('AttackTime',0.01,'SampleRate',Fs);
audioout = dRC(yx);
end
plot(app.UIAxes2,yx);

        end

        % Button pushed function: PlayButton
        function playvocals(app, event)
            global xm;
            global Fs;
            sound(xm,Fs);
        end

        % Button pushed function: PlayButton_2
        function playdrums(app, event)
             global x1m;
            global Fs;
            sound(x1m,Fs);
        end

        % Button pushed function: PlayButton_3
        function playinstrument(app, event)
            global x2m;
            global Fs;
            sound(x2m,Fs);
        end

        % Button pushed function: ListentothemixButton
        function finalmix(app, event)
            global out;
            global Fs;
            sound(out,Fs);
        end

        % Value changed function: VolumeKnob
        function volume(app, event)
            value = app.VolumeKnob.Value;
            global audioout;
            global Fs;
            global out;
            out = value*audioout;
        end

        % Button pushed function: SaveTheSpectorMixButton
        function SpectorMix(app, event)
            global out;
            global Fs;
            filename = 'SpectorMix.wav';
            audiowrite(filename,out,Fs);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadVocalsButton = uibutton(app.UIFigure, 'push');
            app.LoadVocalsButton.ButtonPushedFcn = createCallbackFcn(app, @loadvocals, true);
            app.LoadVocalsButton.Text = 'Load Vocals ';
            app.LoadDrumsButton = uibutton(app.UIFigure, 'push');
            app.LoadDrumsButton.ButtonPushedFcn = createCallbackFcn(app, @loaddrums, true);
            app.LoadDrumsButton.Text = 'Load Drums ';
            app.LoadInstrumentalButton = uibutton(app.UIFigure, 'push');
            app.LoadInstrumentalButton.ButtonPushedFcn = createCallbackFcn(app, @loadinstrument, true);
            app.LoadInstrumentalButton.Text = 'Load Instrumental';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vocals')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amp')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Drums')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amp')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Instrumental')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Amp')
            app.TheWallOfSoundPluginLabel = uilabel(app.UIFigure);
            app.TheWallOfSoundPluginLabel.Text = '''The Wall Of Sound'' Plugin';
            app.VolumeKnobLabel = uilabel(app.UIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob.Limits = [0 1];
            app.VolumeKnob.MajorTicks = [0 0.5 1];
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @volume, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'MIX')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amp')
            app.TheWallOfSoundButton = uibutton(app.UIFigure, 'push');
            app.TheWallOfSoundButton.ButtonPushedFcn = createCallbackFcn(app, @WallOfSound, true);
            app.TheWallOfSoundButton.Text = '''The Wall Of Sound''';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'philspector276.jpg';
            app.NoiseReductionCheckBox = uicheckbox(app.UIFigure);
            app.NoiseReductionCheckBox.ValueChangedFcn = createCallbackFcn(app, @noisereduction, true);
            app.NoiseReductionCheckBox.Text = 'Noise Reduction';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @playvocals, true);
            app.PlayButton.Text = 'Play ';
            app.PlayButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @playdrums, true);
            app.PlayButton_2.Text = 'Play ';
            app.PlayButton_3 = uibutton(app.UIFigure, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @playinstrument, true);
            app.PlayButton_3.Text = 'Play ';
            app.ListentothemixButton = uibutton(app.UIFigure, 'push');
            app.ListentothemixButton.ButtonPushedFcn = createCallbackFcn(app, @finalmix, true);
            app.ListentothemixButton.Text = 'Listen to the mix';
            app.SaveTheSpectorMixButton = uibutton(app.UIFigure, 'push');
            app.SaveTheSpectorMixButton.ButtonPushedFcn = createCallbackFcn(app, @SpectorMix, true);
            app.SaveTheSpectorMixButton.Text = 'Save ''The Spector Mix''';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end